classdef AddCommodity < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        AddButton       matlab.ui.control.Button
        ExitButton      matlab.ui.control.Button
        ManagerWelcome  matlab.ui.control.Label
        Photo           matlab.ui.control.Image
        ManagerProfile  matlab.ui.control.Label
        Label           matlab.ui.control.Label
        Label_2         matlab.ui.control.Label
        Name            matlab.ui.control.EditField
        Label_3         matlab.ui.control.Label
        Price           matlab.ui.control.NumericEditField
        Label_4         matlab.ui.control.Label
        Inventory       matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        mainapp % Description
        loginAccount
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main)
            app.mainapp = main;
            app.loginAccount = app.mainapp.loginAccount;
            app.Photo.ImageSource = ".\profile_photo\" + string(app.loginAccount) + ".jpg";
            
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            app.UIFigureCloseRequest();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            name = app.Name.Value;
            price = app.Price.Value;
            inventory = app.Inventory.Value;
            load Commodity.mat;
            len = length(CName);
            exist = false;
            for i = 1:len
                if length(char(CName{i,1})) == length(name)                
                    if CName{i,1} == name
                        exist = true;
                        CPrice(i) = price;
                        CInventory(i) = inventory;
                        save Commodity CName CPrice CInventory;
                        warndlg("该商品存在，已经为您更改价格和库存信息！");
                        break;
                    end
                end
            end
            
            if ~exist
                CPrice = [CPrice;price];
                CInventory = [CInventory;inventory];
                CName = [CName;name];
                
                save Commodity CName CPrice CInventory;
                warndlg("添加商品成功！");
            end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = '添加';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = '退出';
            app.ManagerWelcome = uilabel(app.UIFigure);
            app.ManagerWelcome.Text = '欢迎使用爱坤超市系统';
            app.Photo = uiimage(app.UIFigure);
            app.ManagerProfile = uilabel(app.UIFigure);
            app.ManagerProfile.Text = '您的头像';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '操作完记得点击退出哦';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '商品名称';
            app.Name = uieditfield(app.UIFigure, 'text');
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '价格';
            app.Price = uieditfield(app.UIFigure, 'numeric');
            app.Price.Limits = [0 Inf];
            app.Price.ValueDisplayFormat = '%.2f';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '库存';
            app.Inventory = uieditfield(app.UIFigure, 'numeric');
            app.Inventory.Limits = [1 Inf];
            app.Inventory.ValueDisplayFormat = '%.0f';
            app.Inventory.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AddCommodity(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end