classdef ChangeInventory < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        ConfirmButton     matlab.ui.control.Button
        ExitButton        matlab.ui.control.Button
        ManagerWelcome    matlab.ui.control.Label
        Photo             matlab.ui.control.Image
        ManagerProfile    matlab.ui.control.Label
        InquireButton     matlab.ui.control.Button
        Label             matlab.ui.control.Label
        Label_2           matlab.ui.control.Label
        Name              matlab.ui.control.EditField
        Label_3           matlab.ui.control.Label
        NewInventory      matlab.ui.control.NumericEditField
        Label_4           matlab.ui.control.Label
        CurrentInventory  matlab.ui.control.NumericEditField
    end

  
    properties (Access = private)
        mainapp % Description
        loginAccount
        wdlg
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main)
            app.mainapp = main;
            app.loginAccount = app.mainapp.loginAccount;
            app.Photo.ImageSource = ".\profile_photo\" + string(app.loginAccount) + ".jpg";
        end

        % Button pushed function: InquireButton
        function InquireButtonPushed(app, event)
            name = app.Name.Value;
            load Commodity.mat;
            len = length(CName);
            exist = false;
            for i = 1:len
                if length(char(CName{i,1})) == length(name)                
                    if CName{i,1} == name
                        exist = true;
                        app.CurrentInventory.Value = CInventory(i); 
                    end
                end
            end
            if ~exist
                app.wdlg = warndlg("您输入的商品不存在。请重新输入！");
            end
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            name = app.Name.Value;
            newinventory = app.NewInventory.Value;
            if newinventory < 0
                app.wdlg = warndlg("新库存不能为负数！");
            end
            load Commodity.mat;
            len = length(CName);
            exist = false;
            for i = 1:len
                if length(char(CName{i,1})) == length(name)                
                    if CName{i,1} == name
                        exist = true;
                        CInventory(i) = newinventory;
                        save Commodity CName CPrice CInventory;
                        app.wdlg = warndlg("更新库存成功！");
                    end
                end
            end
            
            if ~exist
                app.wdlg = warndlg("您输入的商品不存在。请重新输入！");
            end            
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            app.UIFigureCloseRequest();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = '修改';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = '退出';
            app.ManagerWelcome = uilabel(app.UIFigure);
            app.ManagerWelcome.Text = '欢迎使用爱坤超市系统';
            app.Photo = uiimage(app.UIFigure);
            app.ManagerProfile = uilabel(app.UIFigure);
            app.ManagerProfile.Text = '您的头像';
            app.InquireButton = uibutton(app.UIFigure, 'push');
            app.InquireButton.ButtonPushedFcn = createCallbackFcn(app, @InquireButtonPushed, true);
            app.InquireButton.Text = '查询库存';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '操作完记得点击退出哦';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '商品名称';
            app.Name = uieditfield(app.UIFigure, 'text');
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '修改库存为';
            app.NewInventory = uieditfield(app.UIFigure, 'numeric');
            app.NewInventory.Limits = [0 Inf];
            app.NewInventory.ValueDisplayFormat = '%.0f';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '当前库存';
            app.CurrentInventory = uieditfield(app.UIFigure, 'numeric');
            app.CurrentInventory.ValueDisplayFormat = '%.0f';
            app.CurrentInventory.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ChangeInventory(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end