classdef CheckOut < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        AddCommodity      matlab.ui.control.Button
        ExitButton        matlab.ui.control.Button
        ManagerWelcome    matlab.ui.control.Label
        Label             matlab.ui.control.Label
        Label_2           matlab.ui.control.Label
        Name              matlab.ui.control.EditField
        Label_3           matlab.ui.control.Label
        CurrentInventory  matlab.ui.control.NumericEditField
        Label_4           matlab.ui.control.Label
        Number            matlab.ui.control.NumericEditField
        UITable           matlab.ui.control.Table
        Label_5           matlab.ui.control.Label
        TotalPrice        matlab.ui.control.NumericEditField
        Label_7           matlab.ui.control.Label
        Pay               matlab.ui.control.NumericEditField
        Label_6           matlab.ui.control.Label
        Change            matlab.ui.control.NumericEditField
        ConformButton     matlab.ui.control.Button
        DeletCommodity    matlab.ui.control.Button
        ChangeButton      matlab.ui.control.Button
        ReceiptButton     matlab.ui.control.Button
    end

    
    properties (Access = private)
        mainapp % Description
        totalprice
        Receiptapp 
    end
    
    methods (Access = private)
        
        function [exist,results,index] = findPrice(app,name)
            results = 0;
            load Commodity.mat;
            len = length(CName);
            exist = false;
            index = 0;

            for i = 1:len
                if length(char(CName{i,1})) == length(name)                
                    if CName{i,1} == name
                        exist = true;
                        app.CurrentInventory.Value = CInventory(i); 
                        results = CPrice(i);
                        index = i;
                        break;
                    end
                end
            end
            if ~exist
                warndlg("您输入的商品不存在。请重新输入！");
            end               
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main)
            app.mainapp = main;
            app.totalprice = 0.0;
            
        end

        % Button pushed function: AddCommodity
        function AddCommodityButtonPushed(app, event)
            
            [exist,results,index] = app.findPrice(app.Name.Value);
            price = 0;
            if exist
                price = results;
            else 
                return;
            end
            load Commodity.mat
            if CInventory(index) < app.Number.Value
                warndlg("购买数量不可能大于库存数量，填写错误！！！");
                return;
            end
            
            tempdata=app.UITable.Data;
            
            if isempty(tempdata)
                tempdata = {app.Name.Value,price,app.Number.Value};
                app.UITable.Data = tempdata;
                
            else
                sameind=find(contains(tempdata(:,1),app.Name.Value));
                if isempty(sameind)
                    tempdata=[tempdata;{app.Name.Value,price,app.Number.Value}];
                    app.UITable.Data=tempdata;
                else
                    tempdata{sameind,3}=tempdata{sameind,3} + app.Number.Value;
                    app.UITable.Data=tempdata;
                end
            end
      
            app.CurrentInventory.Value = CInventory(index) - app.Number.Value;
            if app.CurrentInventory.Value< 10
                warndlg("该商品库存过少，您需要提醒经理进货！");
            end
            save Commodity CName CPrice CInventory;
            app.totalprice = app.totalprice + price * app.Number.Value;
            app.TotalPrice.Value = app.totalprice;
            if app.Pay.Value~= 0
                app.Change.Value = app.Pay.Value - app.TotalPrice.Value;
            end
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            app.UIFigureCloseRequest();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Value changed function: Pay
        function PayValueChanged(app, event)
            value = app.Pay.Value;
            app.Change.Value = value - app.TotalPrice.Value;
        end

        % Value changed function: TotalPrice
        function TotalPriceValueChanged(app, event)
            value = app.TotalPrice.Value;
            app.Change.Value = app.Pay.Value - value;
        end

        % Button pushed function: ConformButton
        function ConformButtonPushed(app, event)
            change = app.Change.Value;
            if change < 0 || ( app.Pay.Value==0 && app.TotalPrice.Value ~= 0 )
                warndlg("顾客付的钱不够！！！");
                return;
            end
        
            today = datetime('today');
            load Sales.mat;
            index = find(Date==today);
            if ~isempty(index)
                Sale(index) = Sale(index) + app.TotalPrice.Value;
            else
                Date = [Date,today];
                Sale = [Sale,app.TotalPrice.Value];
            end
            save Sales Sale Date
            names = app.UITable.Data(:,1);
            for i = 1:length(names)
                [~,~,k] = app.findPrice(names{i,1});
                load Commodity
                CInventory(k) = CInventory(k) - app.UITable.Data{i,3};
                save Commodity CName CInventory CPrice
            end
            
        end

        % Button pushed function: DeletCommodity
        function DeletCommodityButtonPushed(app, event)
            tempdata=app.UITable.Data;
            index = app.UITable.UserData;
            if index
                price = tempdata{index,2};
                number = tempdata{index,3};
                app.TotalPrice.Value = app.TotalPrice.Value - price*number;
                app.totalprice = app.TotalPrice.Value;
                
                if app.Pay.Value~=0
                    app.Change.Value = app.Pay.Value - app.totalprice;
                end
                [~,~,index2] = app.findPrice(app.Name.Value);
                tempdata(app.UITable.UserData,:)=[];
                app.UITable.Data=tempdata;
            end
            
            if app.UITable.UserData > length(app.UITable.Data)
                app.UITable.UserData = [];
            end
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            app.UITable.UserData = indices(1);
        end

        % Button pushed function: ChangeButton
        function ChangeButtonPushed(app, event)
            app.Change.Value = app.Pay.Value - app.TotalPrice.Value;
        end

        % Button pushed function: ReceiptButton
        function ReceiptButtonPushed(app, event)
            app.Receiptapp = Receipt;
            app.Receiptapp.Time.Text = string(datetime('now','TimeZone','local','Format','y年M月d日 HH:mm:ss'));
            app.Receiptapp.ReceiptTable.Data = app.UITable.Data;
            app.Receiptapp.TotalPrice.Text = string("总价: "+ string(app.TotalPrice.Value));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.AddCommodity = uibutton(app.UIFigure, 'push');
            app.AddCommodity.ButtonPushedFcn = createCallbackFcn(app, @AddCommodityButtonPushed, true);
            app.AddCommodity.Text = '<<';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = '退出';
            app.ManagerWelcome = uilabel(app.UIFigure);
            app.ManagerWelcome.Text = '欢迎使用爱坤超市系统';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '操作完记得点击退出哦';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '商品名称';
            app.Name = uieditfield(app.UIFigure, 'text');
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '当前库存';
            app.CurrentInventory = uieditfield(app.UIFigure, 'numeric');
            app.CurrentInventory.Limits = [0 Inf];
            app.CurrentInventory.ValueDisplayFormat = '%.0f';
            app.CurrentInventory.Editable = 'off';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '购买数量';
            app.Number = uieditfield(app.UIFigure, 'numeric');
            app.Number.ValueDisplayFormat = '%.0f';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'商品名称'; '价格'; '购买数量'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '商品总价';
            app.TotalPrice = uieditfield(app.UIFigure, 'numeric');
            app.TotalPrice.ValueDisplayFormat = '%.2f';
            app.TotalPrice.ValueChangedFcn = createCallbackFcn(app, @TotalPriceValueChanged, true);
            app.TotalPrice.Editable = 'off';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '付款';
            app.Pay = uieditfield(app.UIFigure, 'numeric');
            app.Pay.ValueDisplayFormat = '%.2f';
            app.Pay.ValueChangedFcn = createCallbackFcn(app, @PayValueChanged, true);
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '找零';
            app.Change = uieditfield(app.UIFigure, 'numeric');
            app.Change.ValueDisplayFormat = '%.2f';
            app.Change.Editable = 'off';
            app.ConformButton = uibutton(app.UIFigure, 'push');
            app.ConformButton.ButtonPushedFcn = createCallbackFcn(app, @ConformButtonPushed, true);
            app.ConformButton.Text = '结账';
            app.DeletCommodity = uibutton(app.UIFigure, 'push');
            app.DeletCommodity.ButtonPushedFcn = createCallbackFcn(app, @DeletCommodityButtonPushed, true);
            app.DeletCommodity.Text = '>>';
            app.ChangeButton = uibutton(app.UIFigure, 'push');
            app.ChangeButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeButtonPushed, true);
            app.ChangeButton.Text = '找零';
            app.ReceiptButton = uibutton(app.UIFigure, 'push');
            app.ReceiptButton.ButtonPushedFcn = createCallbackFcn(app, @ReceiptButtonPushed, true);
            app.ReceiptButton.Text = '小票';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CheckOut(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end