classdef DeleteCommodity < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        DeleteButton    matlab.ui.control.Button
        ExitButton      matlab.ui.control.Button
        ManagerWelcome  matlab.ui.control.Label
        Photo           matlab.ui.control.Image
        ManagerProfile  matlab.ui.control.Label
        Label           matlab.ui.control.Label
        Label_2         matlab.ui.control.Label
        Name            matlab.ui.control.EditField
    end

    
    properties (Access = private)
        mainapp % Description
        loginAccount
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main)
            app.mainapp = main;
            app.loginAccount = app.mainapp.loginAccount;
            app.Photo.ImageSource = ".\profile_photo\" + string(app.loginAccount) + ".jpg";
            
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            app.UIFigureCloseRequest();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            name = app.Name.Value;
            load Commodity.mat;
            len = length(CName);
            exist = false;
            for i = 1:len
                if length(char(CName{i,1})) == length(name)                
                    if CName{i,1} == name
                        exist = true;
                        CName(i) = [];
                        CPrice(i) = [];
                        CInventory(i) = [];
                        save Commodity CName CPrice CInventory;
                        warndlg("删除成功！");
                    end
                end
            end
            
            if ~exist
                warndlg("您输入的商品不存在。请重新输入！");
            end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.DeleteButton = uibutton(app.UIFigure, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = '删除';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = '退出';
            app.ManagerWelcome = uilabel(app.UIFigure);
            app.ManagerWelcome.Text = '欢迎使用爱坤超市系统';
            app.Photo = uiimage(app.UIFigure);
            app.ManagerProfile = uilabel(app.UIFigure);
            app.ManagerProfile.Text = '您的头像';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '操作完记得点击退出哦';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '商品名称';
            app.Name = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DeleteCommodity(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end