classdef InquireCommodity < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        InquireButton     matlab.ui.control.Button
        ExitButton        matlab.ui.control.Button
        ManagerWelcome    matlab.ui.control.Label
        Photo             matlab.ui.control.Image
        ManagerProfile    matlab.ui.control.Label
        Label             matlab.ui.control.Label
        Label_2           matlab.ui.control.Label
        Name              matlab.ui.control.EditField
        Label_3           matlab.ui.control.Label
        CurrentPrice      matlab.ui.control.NumericEditField
        Label_4           matlab.ui.control.Label
        CurrentInventory  matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        mainapp % Description
        loginAccount
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main)
            app.mainapp = main;
            app.loginAccount = app.mainapp.loginAccount;
            app.Photo.ImageSource = ".\profile_photo\" + string(app.loginAccount) + ".jpg";
        end

        % Button pushed function: InquireButton
        function InquireButtonPushed(app, event)
            name = app.Name.Value;
            load Commodity.mat;
            len = length(CName);
            exist = false;
            for i = 1:len
                if length(char(CName{i,1})) == length(name)                
                    if CName{i,1} == name
                        exist = true;
                        app.CurrentInventory.Value = CInventory(i); 
                        CPrice(i)
                        app.CurrentPrice.Value = CPrice(i);
                    end
                end
            end
            if ~exist
                warndlg("您输入的商品不存在。请重新输入！");
            end                   
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            app.UIFigureCloseRequest();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.InquireButton = uibutton(app.UIFigure, 'push');
            app.InquireButton.ButtonPushedFcn = createCallbackFcn(app, @InquireButtonPushed, true);
            app.InquireButton.Text = '查询';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = '退出';
            app.ManagerWelcome = uilabel(app.UIFigure);
            app.ManagerWelcome.Text = '欢迎使用爱坤超市系统';
            app.Photo = uiimage(app.UIFigure);
            app.ManagerProfile = uilabel(app.UIFigure);
            app.ManagerProfile.Text = '您的头像';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '操作完记得点击退出哦';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '商品名称';
            app.Name = uieditfield(app.UIFigure, 'text');
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '当前价格';
            app.CurrentPrice = uieditfield(app.UIFigure, 'numeric');
            app.CurrentPrice.Limits = [0 Inf];
            app.CurrentPrice.ValueDisplayFormat = '%.2f';
            app.CurrentPrice.Editable = 'off';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '当前库存';
            app.CurrentInventory = uieditfield(app.UIFigure, 'numeric');
            app.CurrentInventory.ValueDisplayFormat = '%.0f';
            app.CurrentInventory.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InquireCommodity(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end