classdef Login < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        LoginUI                  matlab.ui.container.Tab
        Label_5                  matlab.ui.control.Label
        Label_6                  matlab.ui.control.Label
        LoginButton              matlab.ui.control.Button
        ExitButton               matlab.ui.control.Button
        EditField_3Label         matlab.ui.control.Label
        AccountBox               matlab.ui.control.EditField
        EditField_4Label         matlab.ui.control.Label
        PasswordBox              matlab.ui.control.EditField
        DropDown_2Label          matlab.ui.control.Label
        RoleDropDownBox          matlab.ui.control.DropDown
        LeftImage                matlab.ui.control.Image
        RightImage               matlab.ui.control.Image
        ManagerUI                matlab.ui.container.Tab
        ManagerWelcome           matlab.ui.control.Label
        ManagerLoginWarn         matlab.ui.control.Label
        ManagerProfilePhoto      matlab.ui.control.Image
        ManagerProfile           matlab.ui.control.Label
        ManagerChangePrice       matlab.ui.control.Button
        ManagerChangeInventory   matlab.ui.control.Button
        ManagerSales             matlab.ui.control.Button
        ManagerChoose            matlab.ui.control.Label
        ManagerExit              matlab.ui.control.Button
        addCommodity             matlab.ui.control.Button
        deleteCommodity          matlab.ui.control.Button
        CashierUI                matlab.ui.container.Tab
        CashierWelcome           matlab.ui.control.Label
        CashierLoginWarn         matlab.ui.control.Label
        CashierProfilePhoto      matlab.ui.control.Image
        CashierProfile           matlab.ui.control.Label
        CashierInquireCommodity  matlab.ui.control.Button
        CashierCheckOut          matlab.ui.control.Button
        CashierChoose            matlab.ui.control.Label
        CashierExit              matlab.ui.control.Button
    end

    
    properties (Access = public)
        cashierAccounts % Description
        cashierPassword
        managerAccounts
        managerPassword
        
        loginStatus
        loginRole
        loginAccount
        
        CurrentPassword
        
        ChangeInventoryUI 
        ChangePriceUI
        InquireCommodityUI 
        CashierCheckOutUI
        SalesUI
        AddCommodityUI1
        DeleteCommodityUI1
        
        wdlg
    end
    
    methods (Access = private)
        
        function results = managerLogin(app,account,pw)
            results = false;
            len = length(app.managerAccounts);
            for i = 1:len
                if length(app.managerAccounts{i,1})==length(account) && length(app.managerPassword{i,1})==length(pw) 
                    if app.managerAccounts{i,1} == account
                        if app.managerPassword{i,1} == pw
                            results = true;
                            break; 
                        end
                    end
                end
            end
        end
        
        function results = cashierLogin(app,account,pw)
            results = false;
            len = length(app.cashierAccounts);
            for i = 1:len
                if length(app.cashierPassword{i,1})==length(pw) && length(app.cashierAccounts{i,1})==length(account) 
                    if  app.cashierAccounts{i,1} == account 
                        if  app.cashierPassword{i,1} == pw
                            results = true;
                            break;      
                        end 
                    end 
                end
            end            
        end
        
        
        function results = checkAccountAndPw(app,account,pw)
            results = true;
            
            len1 = length(account);
            for i = 1:len1
                if ~app.isASCII(account(i))
                    app.wdlg = warndlg("您的账号中含有不合规字符！");
                    results = false;
                    return;
                end
            end
            
            len2 = length(pw);
            for i = 1:len2
                if ~app.isASCII(pw(i))
                    app.wdlg = warndlg("您的密码中含有不合规字符！");
                    results = false;
                    return;
                end
            end
            
        end
        
        function results = isASCII(~,c)
            results = (abs(c) < 127 && abs(c) > 31);
        end
        
        function  ManagerUIOn(app)
            app.ManagerLoginWarn.Enable = 'off';
            app.ManagerLoginWarn.Visible = 'off';
            
            app.ManagerWelcome.Visible = 'on';
            app.ManagerWelcome.Enable = 'on';
            app.ManagerProfilePhoto.Visible = 'on';
            app.ManagerProfilePhoto.Enable = 'on';
            app.ManagerProfile.Visible = 'on';
            app.ManagerProfile.Enable = 'on';
            app.ManagerChangePrice.Visible = 'on';
            app.ManagerChangePrice.Enable = 'on';
            app.ManagerChangeInventory.Visible = 'on';
            app.ManagerChangeInventory.Enable = 'on';
            app.ManagerSales.Visible = 'on';
            app.ManagerSales.Enable = 'on';
            app.ManagerChoose.Visible = 'on';
            app.ManagerChoose.Enable = 'on';
            app.ManagerExit.Visible = 'on';
            app.ManagerExit.Enable = 'on';
            app.deleteCommodity.Visible = 'on';
            app.deleteCommodity.Enable = 'on';
            app.addCommodity.Visible = 'on';
            app.addCommodity.Enable = 'on';
        end
        

        function  ManagerUIOff(app)
            app.ManagerLoginWarn.Enable = 'on';
            app.ManagerLoginWarn.Visible = 'on';
            
            app.ManagerWelcome.Visible = 'off';
            app.ManagerWelcome.Enable = 'off';
            app.ManagerProfilePhoto.Visible = 'off';
            app.ManagerProfilePhoto.Enable = 'off';
            app.ManagerProfile.Visible = 'off';
            app.ManagerProfile.Enable = 'off';
            app.ManagerChangePrice.Visible = 'off';
            app.ManagerChangePrice.Enable = 'off';
            app.ManagerChangeInventory.Visible = 'off';
            app.ManagerChangeInventory.Enable = 'off';
            app.ManagerSales.Visible = 'off';
            app.ManagerSales.Enable = 'off';
            app.ManagerChoose.Visible = 'off';
            app.ManagerChoose.Enable = 'off';
            app.ManagerExit.Visible = 'off';
            app.ManagerExit.Enable = 'off';
            app.deleteCommodity.Visible = 'off';
            app.deleteCommodity.Enable = 'off';
            app.addCommodity.Visible = 'off';
            app.addCommodity.Enable = 'off';
            
        end 
        
        
        function  CashierUIOn(app)
            app.CashierLoginWarn.Enable = 'off';
            app.CashierLoginWarn.Visible = 'off';
            
            app.CashierWelcome.Visible = 'on';
            app.CashierWelcome.Enable = 'on';
            app.CashierProfilePhoto.Visible = 'on';
            app.CashierProfilePhoto.Enable = 'on';
            app.CashierProfile.Visible = 'on';
            app.CashierProfile.Enable = 'on';
            app.CashierInquireCommodity.Visible = 'on';
            app.CashierInquireCommodity.Enable = 'on';
            app.CashierCheckOut.Visible = 'on';
            app.CashierCheckOut.Enable = 'on';
            
            app.CashierChoose.Visible = 'on';
            app.CashierChoose.Enable = 'on';
            app.CashierExit.Visible = 'on';
            app.CashierExit.Enable = 'on';
        end
        
        function  CashierUIOff(app)
            app.CashierLoginWarn.Enable = 'on';
            app.CashierLoginWarn.Visible = 'on';
            
            app.CashierWelcome.Visible = 'off';
            app.CashierWelcome.Enable = 'off';
            app.CashierProfilePhoto.Visible = 'off';
            app.CashierProfilePhoto.Enable = 'off';
            app.CashierProfile.Visible = 'off';
            app.CashierProfile.Enable = 'off';
            app.CashierInquireCommodity.Visible = 'off';
            app.CashierInquireCommodity.Enable = 'off';
            app.CashierCheckOut.Visible = 'off';
            app.CashierCheckOut.Enable = 'off';
            
            app.CashierChoose.Visible = 'off';
            app.CashierChoose.Enable = 'off';
            app.CashierExit.Visible = 'off';
            app.CashierExit.Enable = 'off';
        end       
        
        function results = ManagerLoadPhoto(app)
            app.ManagerProfilePhoto.ImageSource = ".\profile_photo\" + string(app.loginAccount) + ".jpg";
        end

        function results = CashierLoadPhoto(app)
            app.CashierProfilePhoto.ImageSource = ".\profile_photo\" + string(app.loginAccount) + ".jpg";
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.wdlg = "";
            app.loginStatus = false;
            app.LeftImage.ImageSource = ".\left.jpg";
            app.RightImage.ImageSource = ".\right.jpg";
            app.CashierUIOff();
            app.ManagerUIOff();
            cashierDB = ".\cashier.txt";
            managerDB = ".\manager.txt";
            [app.cashierAccounts,app.cashierPassword] = textread(cashierDB,'%s %s');
            [app.managerAccounts,app.managerPassword] = textread(managerDB,'%s %s');
        end

        % Button pushed function: LoginButton
        function LoginButtonPushed(app, event)
            role = app.RoleDropDownBox.Value;
            account = app.AccountBox.Value;
            pw = app.PasswordBox.Value;
            
            if ~app.checkAccountAndPw(account,pw)
                return;
            end
            if role == "经理"
                if ~app.managerLogin(account,pw)
                    app.wdlg = warndlg("账号或密码有误！");
                else
                    app.loginStatus = true;
                    app.loginRole = "经理";
                    app.loginAccount = account;
                    app.PasswordBox.Value = '';
                    app.wdlg = warndlg("登录成功，您可以前往经理界面进行操作了！");
                    app.CashierUIOff();
                    app.ManagerUIOn();
                    app.ManagerLoadPhoto();
                end
                
            elseif role == "收银员"
                if ~app.cashierLogin(account,pw)
                    app.wdlg = warndlg("账号或密码有误！");
                else
                    app.loginStatus = true;
                    app.loginRole = "收银员";
                    app.loginAccount = account;
                    app.PasswordBox.Value = '';
                    app.wdlg = warndlg("登录成功，您可以前往收银员界面进行操作了！");
                    app.ManagerUIOff();
                    app.CashierUIOn();
                    app.CashierLoadPhoto();
                end
            end
            
            
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            app.UIFigureCloseRequest();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Button pushed function: ManagerExit
        function ManagerExitButtonPushed(app, event)
            app.ManagerUIOff();
        end

        % Button pushed function: CashierExit
        function CashierExitButtonPushed(app, event)
            app.CashierUIOff();
        end

        % Button pushed function: ManagerChangePrice
        function ManagerChangePriceButtonPushed(app, event)
            app.ChangePriceUI = ChangePrice(app);
            
        end

        % Button pushed function: ManagerChangeInventory
        function ManagerChangeInventoryButtonPushed(app, event)
            app.ChangeInventoryUI = ChangeInventory(app);
        end

        % Button pushed function: CashierInquireCommodity
        function CashierInquireCommodityButtonPushed(app, event)
            app.InquireCommodityUI = InquireCommodity(app);
        end

        % Button pushed function: CashierCheckOut
        function CashierCheckOutButtonPushed(app, event)
            app.CashierCheckOutUI = CheckOut(app);
        end

        % Button pushed function: ManagerSales
        function ManagerSalesButtonPushed(app, event)
            app.SalesUI = Sales(app);
        end

        % Button pushed function: addCommodity
        function addCommodityButtonPushed(app, event)
            app.AddCommodityUI1 = AddCommodity(app);
        end

        % Button pushed function: deleteCommodity
        function deleteCommodityButtonPushed(app, event)
            app.DeleteCommodityUI1 = DeleteCommodity(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LoginUI = uitab(app.TabGroup);
            app.LoginUI.Title = '登录';
            app.Label_5 = uilabel(app.LoginUI);
            app.Label_5.Text = {'登录'; ''};
            app.Label_6 = uilabel(app.LoginUI);
            app.Label_6.Text = '欢迎使用爱坤超市系统';
            app.LoginButton = uibutton(app.LoginUI, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @LoginButtonPushed, true);
            app.LoginButton.Text = '登录';
            app.ExitButton = uibutton(app.LoginUI, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = '退出';
            app.EditField_3Label = uilabel(app.LoginUI);
            app.EditField_3Label.Text = '账号';
            app.AccountBox = uieditfield(app.LoginUI, 'text');
            app.EditField_4Label = uilabel(app.LoginUI);
            app.EditField_4Label.Text = '密码';
            app.PasswordBox = uieditfield(app.LoginUI, 'text');
            app.DropDown_2Label = uilabel(app.LoginUI);
            app.DropDown_2Label.Text = '身份';
            app.RoleDropDownBox = uidropdown(app.LoginUI);
            app.RoleDropDownBox.Items = {'经理', '收银员'};
            app.RoleDropDownBox.Value = '经理';
            app.LeftImage = uiimage(app.LoginUI);
            app.RightImage = uiimage(app.LoginUI);
            app.ManagerUI = uitab(app.TabGroup);
            app.ManagerUI.Title = '经理';
            app.ManagerWelcome = uilabel(app.ManagerUI);
            app.ManagerWelcome.Text = '欢迎使用爱坤超市系统';
            app.ManagerLoginWarn = uilabel(app.ManagerUI);
            app.ManagerLoginWarn.Enable = 'off';
            app.ManagerLoginWarn.Visible = 'off';
            app.ManagerLoginWarn.Text = '请先前往登录界面登录！';
            app.ManagerProfilePhoto = uiimage(app.ManagerUI);
            app.ManagerProfile = uilabel(app.ManagerUI);
            app.ManagerProfile.Text = '您的头像';
            app.ManagerChangePrice = uibutton(app.ManagerUI, 'push');
            app.ManagerChangePrice.ButtonPushedFcn = createCallbackFcn(app, @ManagerChangePriceButtonPushed, true);
            app.ManagerChangePrice.Text = '修改价格';
            app.ManagerChangeInventory = uibutton(app.ManagerUI, 'push');
            app.ManagerChangeInventory.ButtonPushedFcn = createCallbackFcn(app, @ManagerChangeInventoryButtonPushed, true);
            app.ManagerChangeInventory.Text = '修改库存';
            app.ManagerSales = uibutton(app.ManagerUI, 'push');
            app.ManagerSales.ButtonPushedFcn = createCallbackFcn(app, @ManagerSalesButtonPushed, true);
            app.ManagerSales.Text = '销售状况';
            app.ManagerChoose = uilabel(app.ManagerUI);
            app.ManagerChoose.Text = '请选择您要进行的操作';
            app.ManagerExit = uibutton(app.ManagerUI, 'push');
            app.ManagerExit.ButtonPushedFcn = createCallbackFcn(app, @ManagerExitButtonPushed, true);
            app.ManagerExit.Text = '退出登录';
            app.addCommodity = uibutton(app.ManagerUI, 'push');
            app.addCommodity.ButtonPushedFcn = createCallbackFcn(app, @addCommodityButtonPushed, true);
            app.addCommodity.Text = '增加商品';
            app.deleteCommodity = uibutton(app.ManagerUI, 'push');
            app.deleteCommodity.ButtonPushedFcn = createCallbackFcn(app, @deleteCommodityButtonPushed, true);
            app.deleteCommodity.Text = '删除商品';
            app.CashierUI = uitab(app.TabGroup);
            app.CashierUI.Title = '收银员';
            app.CashierWelcome = uilabel(app.CashierUI);
            app.CashierWelcome.Text = '欢迎使用爱坤超市系统';
            app.CashierLoginWarn = uilabel(app.CashierUI);
            app.CashierLoginWarn.Enable = 'off';
            app.CashierLoginWarn.Visible = 'off';
            app.CashierLoginWarn.Text = '请先前往登录界面登录！';
            app.CashierProfilePhoto = uiimage(app.CashierUI);
            app.CashierProfile = uilabel(app.CashierUI);
            app.CashierProfile.Text = '您的头像';
            app.CashierInquireCommodity = uibutton(app.CashierUI, 'push');
            app.CashierInquireCommodity.ButtonPushedFcn = createCallbackFcn(app, @CashierInquireCommodityButtonPushed, true);
            app.CashierInquireCommodity.Text = '查看商品信息';
            app.CashierCheckOut = uibutton(app.CashierUI, 'push');
            app.CashierCheckOut.ButtonPushedFcn = createCallbackFcn(app, @CashierCheckOutButtonPushed, true);
            app.CashierCheckOut.Text = '结账';
            app.CashierChoose = uilabel(app.CashierUI);
            app.CashierChoose.Text = '请选择您要进行的操作';
            app.CashierExit = uibutton(app.CashierUI, 'push');
            app.CashierExit.ButtonPushedFcn = createCallbackFcn(app, @CashierExitButtonPushed, true);
            app.CashierExit.Text = '退出登录';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Login

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end