classdef Sales < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        InquireButton   matlab.ui.control.Button
        ExitButton      matlab.ui.control.Button
        ManagerWelcome  matlab.ui.control.Label
        Label_3         matlab.ui.control.Label
        StartDate       matlab.ui.control.DatePicker
        Label_4         matlab.ui.control.Label
        EndDate         matlab.ui.control.DatePicker
        Label_5         matlab.ui.control.Label
        DropDown        matlab.ui.control.DropDown
        UIAxes          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        mainapp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main)
            app.mainapp = main;
        end

        % Button pushed function: InquireButton
        function InquireButtonPushed(app, event)
%             x = 1:0.1:2;
%             plot(app.UIAxes,x,exp(x));
            
            starttime = app.StartDate.Value;
            endtime = app.EndDate.Value;
            datelist = [];
            temp = starttime;
            
            
            while temp < endtime + 1
                datelist = [datelist,temp];
                temp = temp + 1;
            end            
            load Sales.mat
            salesamount = [];
            for i = 1:length(datelist)
                index = find(Date == datelist(i));
                if isempty(index)
                    salesamount = [salesamount,0];
                else
                    salesamount = [salesamount,Sale(index)];
                end
            end
            
           if app.DropDown.Value == "折线图"
               reset(app.UIAxes); 
               plot(app.UIAxes,datelist,salesamount);
                xlabel(app.UIAxes,"日期");
                ylabel(app.UIAxes,"营业额");
                title(app.UIAxes,"营业额随时间变化折线图");
                
           elseif app.DropDown.Value == "直方图"
               reset(app.UIAxes);
               histogram(app.UIAxes,salesamount,'NumBins',10);
               ylabel(app.UIAxes,"天数");
               xlabel(app.UIAxes,"营业款")
               title(app.UIAxes,"营业额直方图");
           end 
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            app.UIFigureCloseRequest();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.InquireButton = uibutton(app.UIFigure, 'push');
            app.InquireButton.ButtonPushedFcn = createCallbackFcn(app, @InquireButtonPushed, true);
            app.InquireButton.Text = '查询';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = '退出';
            app.ManagerWelcome = uilabel(app.UIFigure);
            app.ManagerWelcome.Text = '欢迎使用爱坤超市系统';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '开始日期';
            app.StartDate = uidatepicker(app.UIFigure);
            app.StartDate.Value = datetime([2019 5 15]);
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '结束日期';
            app.EndDate = uidatepicker(app.UIFigure);
            app.EndDate.Value = datetime([2019 5 30]);
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '图表类型';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'直方图', '折线图'};
            app.DropDown.Value = '直方图';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '销售信息')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Sales(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end